classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImageProcessingAppPanel  matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        LoadButton               matlab.ui.control.Button
        RowButton                matlab.ui.control.Button
        ColumnButton             matlab.ui.control.Button
        GrayscaleButton          matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        SaveButton               matlab.ui.control.Button
        BrightnessButton         matlab.ui.control.Button
        MultiplyButton           matlab.ui.control.Button
        PowerButton              matlab.ui.control.Button
        Rotate90Button           matlab.ui.control.Button
        MirrorUDButton           matlab.ui.control.Button
        MirrorLRButton           matlab.ui.control.Button
        ResizeButton             matlab.ui.control.Button
        QuitButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global image1
            [filename, pathname] = uigetfile({'*.jpg'}, 'FileSelector');
            fullpathname = strcat(pathname, filename);
            image1 = imread(fullpathname);
            imshow(image1, 'Parent', app.UIAxes);
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            global image1
            global image2
            image2 = rgb2gray(image1);
            imshow(image2, 'Parent', app.UIAxes2);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global image2
            [filename, pathname] = uiputfile({'*.jpg'}, 'FileSelector');
            fullpath = strcat(pathname, filename);
            imwrite(image2, fullpath)
           
        end

        % Button pushed function: RowButton
        function RowButtonPushed(app, event)
            global image2
            image2 = zeros(1,256);
            for i = 2:256
                image2(i,:) = image2(i-1,:)+1;
            end
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: ColumnButton
        function ColumnButtonPushed(app, event)
            global image2
            image2 = zeros(256,1);
            for i = 2:256
                image2(:,i) = image2(:,i-1)+1;
            end
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: BrightnessButton
        function BrightnessButtonPushed(app, event)
            global image1
            global image2
            c = input('insert>0 for a bright image & insert<0 for a darker image');
           
             image2 = image1+c;
                
            
            imshow(image2, 'Parent', app.UIAxes2);
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
             global image1
             global image2
            e = input('insert a number to multiply to contrast the image');
            image2 = (image1*e);
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Size changed function: ImageProcessingAppPanel
        function ImageProcessingAppPanelSizeChanged(app, event)
            position = app.ImageProcessingAppPanel.Position;
            
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            global image1
            global image2
            h = input('insert an exponent');
            %[x,y] = size(image1);
            %for i = 1:1:x
                %for j = 1:1:y
             image2 = (image1.^h);
                %end
            %end
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: MirrorUDButton
        function MirrorUDButtonPushed(app, event)
            global image1
            global image2
            image2 = flipud(image1);
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: MirrorLRButton
        function MirrorLRButtonPushed(app, event)
            global image1
            global image2
            image2 = fliplr(image1);
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: Rotate90Button
        function Rotate90ButtonPushed(app, event)
            global image1
            global image2
            image2 = rot90(image1);
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            global image1
            global image2
            l = input('new length');
            w = input('new width');
            image2 = imresize(image1, [l w]);
            size(image2)
            imshow(image2, 'Parent', app.UIAxes2)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
          close all force
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageProcessingAppPanel = uipanel(app.UIFigure);
            app.ImageProcessingAppPanel.Title = 'Image Processing App';
            app.ImageProcessingAppPanel.SizeChangedFcn = createCallbackFcn(app, @ImageProcessingAppPanelSizeChanged, true);
            app.UIAxes = uiaxes(app.ImageProcessingAppPanel);
            title(app.UIAxes, 'Original Photo')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RowButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.RowButton.ButtonPushedFcn = createCallbackFcn(app, @RowButtonPushed, true);
            app.RowButton.Text = 'Row';
            app.ColumnButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.ColumnButton.ButtonPushedFcn = createCallbackFcn(app, @ColumnButtonPushed, true);
            app.ColumnButton.Text = 'Column';
            app.GrayscaleButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.UIAxes2 = uiaxes(app.ImageProcessingAppPanel);
            title(app.UIAxes2, 'Updated Photo')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SaveButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BrightnessButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.BrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessButtonPushed, true);
            app.BrightnessButton.Text = 'Brightness';
            app.MultiplyButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.PowerButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.Rotate90Button = uibutton(app.ImageProcessingAppPanel, 'push');
            app.Rotate90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed, true);
            app.Rotate90Button.Text = 'Rotate(90)';
            app.MirrorUDButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.MirrorUDButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorUDButtonPushed, true);
            app.MirrorUDButton.Text = 'Mirror(UD)';
            app.MirrorLRButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.MirrorLRButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorLRButtonPushed, true);
            app.MirrorLRButton.Text = 'Mirror(LR)';
            app.ResizeButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.QuitButton = uibutton(app.ImageProcessingAppPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end