classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        LoadButton            matlab.ui.control.Button
        LowPassButton         matlab.ui.control.Button
        EdgeDetectionButton   matlab.ui.control.Button
        HighboostButton       matlab.ui.control.Button
        GammaButton           matlab.ui.control.Button
        HistogramButton       matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        AdaptHistogramButton  matlab.ui.control.Button
        LaplacianButton       matlab.ui.control.Button
        GaussianButton        matlab.ui.control.Button
        BrightnessButton      matlab.ui.control.Button
        LoGButton             matlab.ui.control.Button
        ContrastButton        matlab.ui.control.Button
        FIdealButton          matlab.ui.control.Button
        ButterworthButton     matlab.ui.control.Button
        BandpassButton        matlab.ui.control.Button
        BandstopButton        matlab.ui.control.Button
        HomomorphicButton     matlab.ui.control.Button
        QuitButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [] = ifftshow(f)
            %UNTITLED3 Summary of this function goes here
            % %   Detailed explanation goes here
            f1 = abs(f)
            fm = max(f1(:));
            j = (f1/fm);
        end
        
        function [] = fftshow(f)
            %UNTITLED2 Summary of this function goes here
            % %   Detailed explanation goes here
            f1 = log(1+abs(f));
            fm = max(f1(:));
            image2 = (im2uint8(f1/fm));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global image1
            [filename, pathname] = uigetfile({'*.jpg'}, 'FileSelector');
            fullpathname = strcat(pathname, filename);
            image1 = imread(fullpathname);
            imshow(image1, 'Parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global image2
            [filename, pathname] = uiputfile({'*.jpg'}, 'FileSelector');
            fullpath = strcat(pathname, filename);
            imwrite(image2, fullpath)
        end

        % Button pushed function: LowPassButton
        function LowPassButtonPushed(app, event)
            global image1
            global image2
            n = input("insert one integer for square mask size\n");
            h = ones(n,n)/n^2;
            image2 = imfilter(image1,h);
            imshow(image2, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
             global image1
             global image2
             image3 = rgb2gray(image1);
             n = input("insert 1 for Canny and 2 for Sobel");
             if n == 1
              image2 = edge(image3, 'Canny');
             else n == 2
               image2 = edge(image3, 'sobel');  
                 
                 
             end
             
             
             imshow(image2, 'Parent', app.UIAxes_2);
             
        end

        % Callback function
        function EdgeDetectionDropDownValueChanged(app, event)
            
        end

        % Button pushed function: HighboostButton
        function HighboostButtonPushed(app, event)
            global image1
            global image2
            n = input("Insert Boost scaling coefficient");
            % create gaussian filter
            h = fspecial('gaussian',5,2.5);
            % blur the image
            blurred_img = imfilter(image1,h);
            % subtract blurred image from original
            diff_img = image1 - blurred_img;
            % add difference to the original image
            image2 = image1 + n*diff_img;
            imshow(image2, 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: GammaButton
        function GammaButtonPushed(app, event)
            global image1
            global image2
            n = input("Insert an adjustment value in range 0 to 1");
            image2 = imadjust(image1,[],[],n);
            imshow(image2, 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            global image1
            global image2
            image2 = histeq(image1);
            imshow(image2, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: AdaptHistogramButton
        function AdaptHistogramButtonPushed(app, event)
            global image1
            global image2
            image3 = rgb2gray(image1);
            image2 = adapthisteq(image3);
            imshow(image2, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: LoGButton
        function LoGButtonPushed(app, event)
            global image1
            global image2
            n = input("press 1 for spatial domain filter or 2 for frequency domain filter\n")
            if n == 1
                Log_filter = fspecial('log', [5,5], 0.5);
                image2 = imfilter(image1, Log_filter, 'symmetric', 'conv');
                imshow(image2, 'Parent', app.UIAxes_2);
            else n == 2
                a = imresize(image1,[256,256]);
                g =  fspecial("log", 256, 1);
                max(g(:));
                g1 = mat2gray(g);
                max(g1(:));
                af = fftshift(fft2(a));
                ag1 = af.*g1;
                ag1i = ifft2(ag1);
                f1 = abs(ag1i);
                fm = max(f1(:));
                image2 = (f1/fm);
                imshow(image2, 'Parent', app.UIAxes_2);
             end
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            global image1
            global image2
            n = input("Press 1 for spatial domain filter or 2 for frequency domain filter\n")
            if n == 1
                Lap_filter = fspecial('laplacian', 0.5);
                image2 = imfilter(image1, Lap_filter, 'symmetric', 'conv');
                imshow(image2, 'Parent', app.UIAxes_2);
            else n == 2
                imagek = rgb2gray(image1);
                image=im2double(imagek);
                [height width]=size(image);
                image2=zeros(height,width);
                for x=1:height
                    for y=1:width
                        image2(x,y)=image(x,y)*((-1)^(x+y));
                    end
                end
                spectrum=fft2(image2);
                [height width]=size(spectrum);
                filter=ones([height width]); %creates filter template
                center_height=fix(height/2+.5);
                center_width=fix(width/2+.5);
                for u=1:height
                    for v=1:width
                        filter(u,v)=-((u-center_height).^2+(v-center_width).^2);
                    end
                end
                spectrum2=spectrum.*filter;
                limage=real(ifft2(spectrum2));
                f1 = abs(limage);
                fm = max(f1(:));
                image2 = (f1/fm);
                imshow(image2, 'Parent', app.UIAxes_2);
            end
            
            
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
             global image1
             global image2
             n = input("Press 1 for spatial domain gaussian filter or 2 for frequency domain gaussian filter\n");
             d = input("Please insert the standard deviation");
             if n == 1
                 j = input("Please insert the window size");
                 Lap_filter = fspecial('gaussian',j,d);
                 image2 = imfilter(image1, Lap_filter, 'symmetric', 'conv');
                 imshow(image2, 'Parent', app.UIAxes_2);
             else n == 2
                 a = imresize(image1,[256,256]);
                 g =  fspecial("gaussian", 256, d);
                 max(g(:));
                 g1 = mat2gray(g);
                 max(g1(:));
                 af = fftshift(fft2(a));
                 ag1 = af.*g1;
                 ag1i = ifft2(ag1);
                 f1 = abs(ag1i);
                 fm = max(f1(:));
                 image2 = (f1/fm);
                 imshow(image2, 'Parent', app.UIAxes_2);
             end
             
        end

        % Button pushed function: BrightnessButton
        function BrightnessButtonPushed(app, event)
            global image1
            global image2
            c = input('insert>0 for a bright image & insert<0 for a darker image');
             image2 = image1+c;
                
            imshow(image2, 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
             global image1
             global image2
            e = input('insert a number to multiply to contrast the image');
            image2 = (image1*e);
            imshow(image2, 'Parent', app.UIAxes_2)
        end

        % Button pushed function: FIdealButton
        function FIdealButtonPushed(app, event)
             global image1
             global image2
             c = rgb2gray(image1);
             a = imresize(c,[256,256]);
             n = input("press 1 for Low pass or  2 for High pass filter\n")
             u = input("Please insert your desired radius\n");
                 af = fftshift(fft2(a));
                 %f1 = log(1+abs(af));
                 %fm = max(f1(:));
                 %j = im2uint8(f1/fm);
                 [x,y]= meshgrid(-128:127,-128:127);
                 k = sqrt(x.^2+y.^2);
             if n == 1
                 c = k<u;
                 af1 = af.*c;
                 f2 = log(1+abs(af1));
                 fm2 = max(f2(:));
                 %j2 = im2uint8(f1/fm);
                 af1i = ifft2(af1);
                 f1 = abs(af1i);
                 fm = max(f1(:));
                 image2 = (f1/fm);
                 imshow(image2, 'Parent', app.UIAxes_2);
             else n == 2
                 c = k>u;
                 af1 = af.*c;
                 f2 = log(1+abs(af1));
                 fm2 = max(f2(:));
                 af1i = ifft2(af1);
                 f1 = abs(af1i);
                 fm = max(f1(:));
                 image2 = (f1/fm);
                 imshow(image2, 'Parent', app.UIAxes_2);
             end
        
        end

        % Button pushed function: ButterworthButton
        function ButterworthButtonPushed(app, event)
            global image1
            global image2
            k = input("Press 1 for low pass filter or 2 for high pass filter");
            d = input("Please insert the cutoff frequency");
            n = input("Please insert the order of the filter");
            c = rgb2gray(image1);
            a = imresize(c,[256,256]);
            h = size(a,1);
            w = size(a,2);
            [x,y] = meshgrid(-floor(w/2):floor(w-1)/2, -floor(h/2):floor(h-1)/2);
            hp = 1./(1.+(d./sqrt(x.^2+y.^2))^2*n);
            af = fftshift(fft2(a));
            if k == 2
                afhp = af.*hp;
                afhpi = ifft2(afhp);
                f1 = abs(afhpi);
                fm = max(f1(:));
                image2 = (f1/fm);
                imshow(image2, 'Parent', app.UIAxes_2);
            else
                afhp = af.*(1-hp);
                afhpi = ifft2(afhp);
                f1 = abs(afhpi);
                fm = max(f1(:));
                image2 = (f1/fm);
                imshow(image2, 'Parent', app.UIAxes_2);
                
                
            end
            
        end

        % Button pushed function: BandpassButton
        function BandpassButtonPushed(app, event)
            global image1
            global image2
            a = double(rgb2gray(image1));
            c = double(imresize(a,[256,256]));
            lo = input("Insert radius 1");
            hi = input("Insert radius 2");
            af = fftshift(fft2(c));
            [r,d] = size(c);
            distance = double(zeros(r,d));
            center_row=fix(r/2+.5);
            center_column=fix(d/2+.5);
            for row = 1:r
                for column = 1:d
                    distance(row,column)=sqrt((row-center_row).^2+(column-center_column).^2);
                end
            end
            inner=min([lo hi]);
            outer=max([lo hi]);
            if distance(row,column)>= inner && distance(row,column) <= outer;
                af1 = af.*distance;
            end
            af1i = ifft2(af1);
            f1 = abs(af1i);
            fm = max(f1(:));
            image2 = (f1/fm);
            imshow(image2, 'Parent', app.UIAxes_2)


        end

        % Button pushed function: BandstopButton
        function BandstopButtonPushed(app, event)
            c = double(rgb2gray(k));
            lo = input("Insert radius 1");
            hi = input("Insert radius 2");
            af = fftshift(fft2(c));
            [r,d] = size(c);
            distance = double(zeros(r,d));
            center_row=fix(r/2+.5);
            center_column=fix(d/2+.5);
            for row = 1:r
               for column = 1:d
                   distance(row,column)=sqrt((row-center_row).^2+(column-center_column).^2);
               end
            end
            inner=min([lo hi]);
            outer=max([lo hi]);
            if distance(row,column)<= inner && distance(row,column) >= outer;
                af1 = af.*distance;
            end
            af1i = ifft2(af1);
            f1 = abs(af1i);
            fm = max(f1(:));
            image2 = (f1/fm);
            imshow(image2, 'Parent', app.UIAxes_2)

        end

        % Button pushed function: HomomorphicButton
        function HomomorphicButtonPushed(app, event)
            global image1
            global image2
            radius=input("radi");
            gammah = input("gammah");
            gammal = input("gammal");
            k =im2double(image1);
            [height width]=size(k);
            image=zeros(height,width);
            for x=1:height
                for y=1:width
                    image(x,y)=k(x,y)*((-1)^(x+y));
                end
            end
            spectrum=fft2(image);
            [height width]=size(image);
            filter=zeros([height width]);
            center_height=fix(height/2+.5);
            center_width=fix(width/2+.5);
            gaussian=ones(height,width);
            for x=1:height
                for y=1:width
                    D= sqrt((x-center_height).^2+(y-center_width).^2) ;
                    gaussian(x,y)=1-exp(-1*(D.^2)/(2*(radius.^2)));
                end
            end
            filter=((gammah-gammal)*gaussian)+gammal;
            %filter=gaussian;
            spectrum2= spectrum.*filter;
            af1i = ifft2(spectrum2);
            f1 = abs(af1i);
            fm = max(f1(:));
            image2 = (f1/fm);
            imshow(image2, 'Parent', app.UIAxes_2)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            close all force
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Updated Image')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LowPassButton = uibutton(app.UIFigure, 'push');
            app.LowPassButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassButtonPushed, true);
            app.LowPassButton.Text = 'Low-Pass';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.HighboostButton = uibutton(app.UIFigure, 'push');
            app.HighboostButton.ButtonPushedFcn = createCallbackFcn(app, @HighboostButtonPushed, true);
            app.HighboostButton.Text = 'Highboost';
            app.GammaButton = uibutton(app.UIFigure, 'push');
            app.GammaButton.ButtonPushedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaButton.Text = 'Gamma';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AdaptHistogramButton = uibutton(app.UIFigure, 'push');
            app.AdaptHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptHistogramButtonPushed, true);
            app.AdaptHistogramButton.Text = 'Adapt-Histogram';
            app.LaplacianButton = uibutton(app.UIFigure, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.GaussianButton = uibutton(app.UIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.BrightnessButton = uibutton(app.UIFigure, 'push');
            app.BrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessButtonPushed, true);
            app.BrightnessButton.Text = 'Brightness';
            app.LoGButton = uibutton(app.UIFigure, 'push');
            app.LoGButton.ButtonPushedFcn = createCallbackFcn(app, @LoGButtonPushed, true);
            app.LoGButton.Text = 'LoG';
            app.ContrastButton = uibutton(app.UIFigure, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast';
            app.FIdealButton = uibutton(app.UIFigure, 'push');
            app.FIdealButton.ButtonPushedFcn = createCallbackFcn(app, @FIdealButtonPushed, true);
            app.FIdealButton.Text = 'F-Ideal';
            app.ButterworthButton = uibutton(app.UIFigure, 'push');
            app.ButterworthButton.ButtonPushedFcn = createCallbackFcn(app, @ButterworthButtonPushed, true);
            app.ButterworthButton.Text = 'Butterworth';
            app.BandpassButton = uibutton(app.UIFigure, 'push');
            app.BandpassButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassButtonPushed, true);
            app.BandpassButton.Text = 'Bandpass';
            app.BandstopButton = uibutton(app.UIFigure, 'push');
            app.BandstopButton.ButtonPushedFcn = createCallbackFcn(app, @BandstopButtonPushed, true);
            app.BandstopButton.Text = 'Bandstop';
            app.HomomorphicButton = uibutton(app.UIFigure, 'push');
            app.HomomorphicButton.ButtonPushedFcn = createCallbackFcn(app, @HomomorphicButtonPushed, true);
            app.HomomorphicButton.Text = 'Homomorphic';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end